classdef AppLab5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ROUTINESPanel                  matlab.ui.container.Panel
        executionTime                  matlab.ui.control.Label
        lastRoutine                    matlab.ui.control.Label
        currentRoutine                 matlab.ui.control.Label
        LASTROUTINEEXECUTIONTIMELabel  matlab.ui.control.Label
        LASTEXECUTEDROUTINELabel       matlab.ui.control.Label
        CURRENTROUTINELabel            matlab.ui.control.Label
        IMAGEPanel                     matlab.ui.container.Panel
        plotFigura                     matlab.ui.control.Image
        fotoFigura                     matlab.ui.control.Image
        INFOPanel                      matlab.ui.container.Panel
        mensajesDeAlerta               matlab.ui.control.Label
        CURRENTPOSITIONPanel           matlab.ui.container.Panel
        zPosition                      matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        yPosition                      matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        xPosition                      matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        OPTIONSPanel                   matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        INITIALSTab                    matlab.ui.container.Tab
        ZButton                        matlab.ui.control.Button
        MButton                        matlab.ui.control.Button
        NButton                        matlab.ui.control.Button
        SHAPETab                       matlab.ui.container.Tab
        CIRCLEButton                   matlab.ui.control.Button
        RECTANGLEButton                matlab.ui.control.Button
        SELECTROUTUNEPanel             matlab.ui.container.Panel
        DRAWWORKSPACELIMITSButton      matlab.ui.control.Button
        DRAWSHAPEButton                matlab.ui.control.Button
        DRAWNAMEINITIALSButton         matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        EMERGENCYSTOPButton            matlab.ui.control.Button
        UNLOADTOOLButton               matlab.ui.control.Button
        LOADTOOLButton                 matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        INITROSButton                  matlab.ui.control.Button
        CLEANButton                    matlab.ui.control.Button
        AndresMoralesNelsonRamirezAndresZuletaLabel  matlab.ui.control.Label
        ROBOTICA20231SLabel            matlab.ui.control.Label
        LAB5CINEMATICAINVERSALabel     matlab.ui.control.Label
        EMERGENCYSTOPONLamp            matlab.ui.control.Lamp
        EMERGENCYBRAKEONLabel          matlab.ui.control.Label
        TOOLLOADEDLamp                 matlab.ui.control.Lamp
        TOOLLOADEDLampLabel            matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        X_total_letras = 100;  % Cantidad total de puntos para dibujar la letra N
        rospublisher_1;
        pxPub;
        moraPub; 
        moraPubGripper; 

        pxSub;
        L = [12.892 10.669 10.664 9.093];
        isToolLoaded = false;
        isROSactive = false;
    end
    
    methods (Access = private)
        
        function [] = cleanAll(app)
            clear; clc; close all; 
            rosshutdown(); 
        end
    end
    
    methods (Access = public)
        function msg = generar_mensaje(app,points)
            Points_Trayectoria = [];
            % Recorrer los puntos y asignarlos a vec3msg
            for i = 1:size(points, 1)
                vec3msg = rosmessage("geometry_msgs/Vector3");
                vec3msg.X = points(i, 1);
                vec3msg.Y = points(i, 2);
                vec3msg.Z = points(i, 3);
                
                % Agregar vec3msg a Points_Trayectoria
                Points_Trayectoria = [Points_Trayectoria, vec3msg];
            end
            
            % Asignar Points_Trayectoria a msg.Positions
            msg = rosmessage("px_msgs/pedroPascal");
            msg.Positions = Points_Trayectoria;
        end
        function [points_N] = dibujar_letra_N(app,X_total, I_L, J_L, H, X0, Y0)
            % Calcular la cantidad de puntos en el eje X
            X = X_total / 2;
            
            % Definir puntos de la letra N en el plano XY relativo al punto de inicio (X0, Y0)
            x_n1 = linspace(X0, X0, X);
            y_n1 = linspace(Y0, Y0+J_L, X);
            x_n2 = linspace(X0, X0+I_L, X);
            y_n2 = linspace(Y0+J_L, Y0, X);
            x_n3 = linspace(X0+I_L, X0+I_L, X);
            y_n3 = linspace(Y0, Y0+J_L, X);
            
            % Generar puntos para la trayectoria de la letra N en el espacio XYZ
            points_n = [x_n1', y_n1', zeros(X, 1)+H; ...
                        x_n2', y_n2', zeros(X, 1)+H; ...
                        x_n3', y_n3', zeros(X, 1)+H];
            
            % Guardar puntos de la trayectoria en un arreglo
            points_N = points_n;
        end
        
        function [points_M] = dibujar_letra_M(app,X_total, I_L, J_L, H, X0, Y0)
            % Calcular la cantidad de puntos en el eje X
            X = X_total / 4;
            
            % Definir puntos de la letra M en el plano XY relativo al punto de inicio (X0, Y0)
            x_m1 = linspace(X0, X0, X);
            y_m1 = linspace(Y0, Y0+J_L, X);
            x_m2 = linspace(X0, X0+I_L/2, X);
            y_m2 = linspace(Y0+J_L, Y0, X);
            x_m3 = linspace(X0+I_L/2, X0+I_L, X);
            y_m3 = linspace(Y0, Y0+J_L, X);
            x_m4 = linspace(X0+I_L, X0+I_L, X);
            y_m4 = linspace(Y0+J_L, Y0, X);
            
            % Generar puntos para la trayectoria de la letra M en el espacio XYZ
            points_m = [x_m1', y_m1', zeros(X, 1)+H; ...
                        x_m2', y_m2', zeros(X, 1)+H; ...
                        x_m3', y_m3', zeros(X, 1)+H; ...
                        x_m4', y_m4', zeros(X, 1)+H];
            
            % Guardar puntos de la trayectoria en un arreglo
            points_M = points_m;
        end
        
        
        function points_Z = dibujar_letra_Z(app,X_total, I_L, J_L, H, X0, Y0)
            % Calcular la cantidad de puntos en el eje X
            n_points = round(X_total / 3);
            
            % Definir puntos de la letra Z en el plano X'Y
            x_z1 = linspace(0, I_L, n_points);
            y_z1 = linspace(J_L, J_L, n_points);
            x_z2 = linspace(I_L, 0, n_points);
            y_z2 = linspace(J_L, 0, n_points);
            x_z3 = linspace(0, I_L, n_points);
            y_z3 = linspace(0, 0, n_points);
            
            % Generar puntos para la trayectoria de la letra Z en el espacio XYZ
            points_z = [x_z1', y_z1', zeros(n_points, 1)+H; ...
                        x_z2', y_z2', zeros(n_points, 1)+H; ...
                        x_z3', y_z3', ones(n_points, 1)*H];
            
            % Desplazar los puntos a lo largo del eje X e Y según las coordenadas de inicio
            points_z(:, 1) = points_z(:, 1) + X0;
            points_z(:, 2) = points_z(:, 2) + Y0;
            
            % Guardar puntos de la trayectoria en un arreglo
            points_Z = points_z;
        end

        function puntos_rectangulo = dibujarRectanguloCentro(app,N, L, W, H, C)
            % Inicializar los puntos
            puntos_rectangulo = zeros(N, 3);
            
            % Calcular las coordenadas del centro relativas al origen
            centro_x = C(1);
            centro_y = C(2);
            
            % Calcular las mitades de la longitud y el ancho
            mitad_L = L / 2;
            mitad_W = W / 2;
            
            % Generar los puntos para el rectángulo
            puntos_rectangulo(1:N/4, 1) = linspace(-mitad_L, mitad_L, N/4) + centro_x;
            puntos_rectangulo(1:N/4, 2) = -mitad_W + centro_y;
            puntos_rectangulo(1:N/4, 3) = H;
            
            puntos_rectangulo(N/4+1:N/2, 1) = mitad_L + centro_x;
            puntos_rectangulo(N/4+1:N/2, 2) = linspace(-mitad_W, mitad_W, N/4) + centro_y;
            puntos_rectangulo(N/4+1:N/2, 3) = H;
            
            puntos_rectangulo(N/2+1:3*N/4, 1) = linspace(mitad_L, -mitad_L, N/4) + centro_x;
            puntos_rectangulo(N/2+1:3*N/4, 2) = mitad_W + centro_y;
            puntos_rectangulo(N/2+1:3*N/4, 3) = H;
            
            puntos_rectangulo(3*N/4+1:N, 1) = -mitad_L + centro_x;
            puntos_rectangulo(3*N/4+1:N, 2) = linspace(mitad_W, -mitad_W, N/4) + centro_y;
            puntos_rectangulo(3*N/4+1:N, 3) = H;
        end
        function puntos_circulo_centro = dibujarCirculoCentro(app, N, R, H, C)
            % Inicializar los puntos
            puntos_circulo_centro = zeros(N, 3);
            
            % Calcular el ángulo entre cada punto
            angulo = 2 * pi / N;
            
            % Calcular la posición del centro relativo al origen
            centro_x = C(1);
            centro_y = C(2);
            
            % Generar los puntos para el círculo
            for i = 1:N
                theta = angulo * (i-1);
                puntos_circulo_centro(i, 1) = R * cos(theta) + centro_x;
                puntos_circulo_centro(i, 2) = R * sin(theta) + centro_y;
                puntos_circulo_centro(i, 3) = H;
            end
        end
        function puntos_arcos_raya = dibujarArcosSinRayita(app, N, R, theta_start, theta_end, H)
            % Inicializar los puntos
            puntos_arcos_raya = zeros(N, 3);
            
            % Calcular el ángulo entre cada punto
            angulo = (theta_end - theta_start) / (N - 1);
            
            % Generar los puntos para el primer arco
            for i = 1:N/2
                theta = theta_start + angulo * (i-1);
                puntos_arcos_raya(i, 1) = R * cos(theta);
                puntos_arcos_raya(i, 2) = R * sin(theta);
                puntos_arcos_raya(i, 3) = H;
            end
            % Generar los puntos para el segundo arco (espejo)
            for i = 1:N/2
                theta = theta_end - angulo * (i-1);
                puntos_arcos_raya(N/2+i, 1) = R * cos(theta);
                puntos_arcos_raya(N/2+i, 2) = -R * sin(theta);
                puntos_arcos_raya(N/2+i, 3) = H;
            end
        end

        % Rutina Marcador
        function [] = tomarMarcador(app, publisher, L, xCoord, yCoord)
            %posicion de preparacion
            %qs = ikinePX(app, xCoord, yCoord, 20, (0.0)*pi/180 ,L);
            msg = rosmessage("geometry_msgs/Vector3"); 
            msgGripper = rosmessage("std_msgs/Float64"); 

            msg.X = xCoord; 
            msg.Y = yCoord; 
            msg.Z = 20; 
            app.moraPub.send(msg); 
            pause(5); 

            %descenso
            %qs = ikinePX(app, xCoord, yCoord, 12, (0.0)*pi/180 ,L);
            %pose = [qs 0.0];
            %movePX(app, publisher,pose,0.7);
            %pause(5);
            msg.Z = 4.8; 
            app.moraPub.send(msg); 
            pause(5); 
            %agarre
            %%qs = ikinePX(app, xCoord, yCoord, 12, (0.0)*pi/180 ,L);
            %pose = [qs 1.7];
            %movePX(app, publisher,pose,0.7);
            %pause(5);
             msg.Z = 4.8; 
            msgGripper.Data = -0.3; 
            app.moraPubGripper.send(msgGripper); 
            pause(5); 

            %asensio :c (se va del madrid al psg)
            %qs = ikinePX(app, xCoord, yCoord, 20, (0.0)*pi/180 ,L);
            %pose = [qs 1.7];
            msg.Z = 20; 
            app.moraPub.send(msg); 
            pause(5); 
            %movePX(app, publisher,pose,0.7);
        end
        function [] = descargarMarcador(app, publisher, L, xCoord, yCoord)
            %posicion de preparacion
            qs = ikinePX(app, xCoord, yCoord, 20, (0.0)*pi/180 ,L);
            pose = [qs 1.7];
            movePX(app, publisher,pose,0.7);
            pause(5);
            %soltar
            qs = ikinePX(app, xCoord, yCoord, 20, (0.0)*pi/180 ,L);
            pose = [qs 1.7];
            movePX(app, publisher,pose,0.7);
            pause(5);
            
        end
        function [art] = ikinePX(app,x,y,z,th,L)
            q1 = atan2(y,x);
            r = sqrt(x^2+y^2);
            zm = z - L(1) - L(4)*sin(th);
            rm = r - L(4)*cos(th);
            lm = sqrt(zm^2+rm^2);
            alpha = atan2(zm,rm);
            arg = (lm^2+L(2)^2-L(3)^2)/(2*L(2)*lm);
            beta = acos(arg);
            q2 = - pi/2 + alpha + beta;
            gamma = acos((L(2)^2+L(3)^2-lm^2)/(2*L(2)*L(3)));
            q3 = -pi+gamma;
            zc = L(2)*sin(alpha+beta);
            rc = L(2)*cos(alpha+beta);
            phi = atan2(zm-zc,rm-rc);
            q4 = th - phi;
            art = [q1 q2 q3 q4];
        end
        function [] = movePX(app,publisher,pos,time)
            msg2 = rosmessage("trajectory_msgs/JointTrajectory");     
            time_now = rostime("now", "DataFormat", "object"); 
            msg2.Header.Stamp = time_now;
            msg2.JointNames = {'joint_1', 'joint_2', 'joint_3', 'joint_4', 'joint_5'}; 
            pt = rosmessage("trajectory_msgs/JointTrajectoryPoint");
            pt.Positions = pos; 
            pt.TimeFromStart = rosduration(time); 
            msg2.Points = pt; 
            publisher.send(msg2); 
        end
        % Forward Kinematics
        function [Res] = calculaMTH(app,theta_i,d_i,a_i,alpha_i)
            Res = [cosd(theta_i) -sind(theta_i)*cosd(alpha_i) sind(theta_i)*sind(alpha_i) a_i*cosd(theta_i); sind(theta_i) cosd(theta_i)*cosd(alpha_i) -cosd(theta_i)*sind(alpha_i) a_i*sind(theta_i); 0 sind(alpha_i) cosd(alpha_i) d_i; 0 0 0 1];
        end
        function [x, y, z] = forwardKInematics(app,TH1,TH2,TH3,TH4)
            T_Tool_4 = [0 0 1 0; 1 0 0 0; 0 1 0 0; 0 0 0 1];
            T10_4 = calculaMTH(app,TH1+180,L1,0,90); %%(theta,d,a,alpha)
            T21_4 = calculaMTH(app,TH2+90,0,L2,0);
            T32_4 = calculaMTH(app,TH3,0,L3,0);
            T43_4 = calculaMTH(app,TH4,0,L4,0);
            T40_4 = T10_4*T21_4*T32_4*T43_4*T_Tool_4;
            x = T40_4(1,4);
            y = T40_4(2,4);
            z = T40_4(3,4);
        end
        function [] = positionCallback(app,msg)
            app.xPosition = msg.Position(1);
            app.yPosition = msg.Position(2);
            app.zPosition = msg.Position(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NButton
        function NButtonPushed(app, event)
            app.fotoFigura.ImageSource = "nReal.jpeg";
            app.plotFigura.ImageSource = "N.PNG";
            if app.isToolLoaded
                I_L = 5;       % Longitud total en el eje X
                J_L = 5;        % Longitud total en el eje Y
                H = 3;         % Altura respecto al origen (0,0,0)
                X0 = 13;         % Coordenada X del punto de inicio
                Y0 = -13;         % Coordenada Y del punto de inicio
                sec = second(datetime);
                app.currentRoutine.Text = "NAME INITIALS: N";
                points_N = dibujar_letra_N(app,app.X_total_letras, I_L, J_L, H, X0, Y0);
                msg = generar_mensaje(app,points_N);
                app.rospublisher_1.send(msg);
                app.lastRoutine.Text = "NAME INITIALS: N";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "TOOL IS NOT LOADED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: CLEANButton
        function CLEANButtonPushed(app, event)
            clear; clc; close all; 
            rosshutdown(); 
            app.isROSactive = false;
        end

        % Button pushed function: INITROSButton
        function INITROSButtonPushed(app, event)
            rosinit("http://192.168.243.231:11311"); 
            app.rospublisher_1= rospublisher("/matlab_positions", "px_msgs/pedroPascal");
            app.pxPub = rospublisher("/joint_trajectory", "trajectory_msgs/JointTrajectory");
            app.moraPub = rospublisher("/matlab_app_pose", "geometry_msgs/Vector3"); 
            app.moraPubGripper = rospublisher("/matlab_app_gripper", "std_msgs/Float64"); 

            rossubscriber("/joint_states","sensor_msgs/JointState",@app.positionCallback);
            app.isROSactive = true;
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            app.fotoFigura.ImageSource = "mReal.jpeg";
            app.plotFigura.ImageSource = "M.PNG";
            if app.isToolLoaded
                I_L = 5;       % Longitud total en el eje X
                J_L = 5;        % Longitud total en el eje Y
                H = 2.6;         % Altura respecto al origen (0,0,0)
                X0 = 14;         % Coordenada X del punto de inicio
                Y0 = -12;         % Coordenada Y del punto de inicio
                sec = second(datetime);
                app.currentRoutine.Text = "NAME INITIALS: M";
                points_M = dibujar_letra_M(app,app.X_total_letras, I_L, J_L, H, X0, Y0);
                msg = generar_mensaje(app,points_M);
                app.rospublisher_1.send(msg);
                app.lastRoutine.Text = "NAME INITIALS: M";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "TOOL IS NOT LOADED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
            
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            app.fotoFigura.ImageSource = "zReal.jpeg";
            app.plotFigura.ImageSource = "Z.PNG";
            if app.isToolLoaded
                I_L = 5;       % Longitud total en el eje X
                J_L = 5;        % Longitud total en el eje Y
                H = 4.8;         % Altura respecto al origen (0,0,0)
                X0 = 13;         % Coordenada X del punto de inicio
                Y0 = -13;         % Coordenada Y del punto de inicio
                sec = second(datetime);
                app.currentRoutine.Text = "NAME INITIALS: Z";
                points_Z = dibujar_letra_Z(app,app.X_total_letras, I_L, J_L, H, X0, Y0);
                msg = generar_mensaje(app,points_Z);
                app.rospublisher_1.send(msg);
                app.lastRoutine.Text = "NAME INITIALS: Z";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "TOOL IS NOT LOADED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: RECTANGLEButton
        function RECTANGLEButtonPushed(app, event)
            app.fotoFigura.ImageSource = "squareReal.jpeg";
            app.plotFigura.ImageSource = "rectangulo.PNG";
            if app.isToolLoaded
                sec = second(datetime);
                app.currentRoutine.Text = "RECTANGLE";
                points = dibujarRectanguloCentro(app, 100, 10, 10, 4.8, [19,0]);

                msg = generar_mensaje(app,points);
                app.rospublisher_1.send(msg);

                app.lastRoutine.Text = "RECTANGLE";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "TOOL IS NOT LOADED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: CIRCLEButton
        function CIRCLEButtonPushed(app, event)
            app.fotoFigura.ImageSource = "circleReal.jpeg";
            app.plotFigura.ImageSource = "Circulo.PNG";
            if app.isToolLoaded
                sec = second(datetime);
                app.currentRoutine.Text = "CIRCLE";
                points = dibujarCirculoCentro(app, 100, 4, 4.8, [18,-6]);


                msg = generar_mensaje(app,points);
                app.rospublisher_1.send(msg);

                app.lastRoutine.Text = "CIRCLE";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "TOOL IS NOT LOADED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: DRAWWORKSPACELIMITSButton
        function DRAWWORKSPACELIMITSButtonPushed(app, event)
            app.fotoFigura.ImageSource = "wsReal.jpeg";
            app.plotFigura.ImageSource = "workspace.PNG";
            if app.isToolLoaded
                sec = second(datetime);
                app.currentRoutine.Text = "WS LIMITS";
                points = dibujarArcosSinRayita(app, 100, 18, pi/2, 0, 2.5);

                msg = generar_mensaje(app,points);
                app.rospublisher_1.send(msg);

                app.lastRoutine.Text = "WS LIMITS";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "TOOL IS NOT LOADED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: DRAWNAMEINITIALSButton
        function DRAWNAMEINITIALSButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.INITIALSTab;
        end

        % Button pushed function: DRAWSHAPEButton
        function DRAWSHAPEButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SHAPETab;
        end

        % Button pushed function: LOADTOOLButton
        function LOADTOOLButtonPushed(app, event)
            
            if app.isROSactive
                sec = second(datetime);
                app.currentRoutine.Text = "LOADING TOOL";
                tomarMarcador(app, app.pxPub, app.L, 19, 13);
                app.isToolLoaded = true;
                app.TOOLLOADEDLamp.Enable = "on";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "ROS IS NOT ENABLED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: UNLOADTOOLButton
        function UNLOADTOOLButtonPushed(app, event)

            if app.isROSactive
                sec = second(datetime);
                app.currentRoutine.Text = "UNLOADING TOOL";
                descargarMarcador(app, app.pxPub, app.L, 20, -10.5);
                app.isToolLoaded = true;
                app.TOOLLOADEDLamp.Enable = "off";
                app.executionTime.Text = num2str(second(datetime) - sec);
            else
                app.mensajesDeAlerta.Text = "ROS IS NOT ENABLED YET";
                pause(5);
                app.mensajesDeAlerta.Text = "";
            end
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LogoUNAL.png');
            app.Panel_2 = uipanel(app.UIFigure);
            app.TOOLLOADEDLampLabel = uilabel(app.Panel_2);
            app.TOOLLOADEDLampLabel.Enable = 'off';
            app.TOOLLOADEDLampLabel.Text = 'TOOL LOADED';
            app.TOOLLOADEDLamp = uilamp(app.Panel_2);
            app.TOOLLOADEDLamp.Enable = 'off';
            app.EMERGENCYBRAKEONLabel = uilabel(app.Panel_2);
            app.EMERGENCYBRAKEONLabel.Enable = 'off';
            app.EMERGENCYBRAKEONLabel.Text = {'EMERGENCY'; 'STOP ON'};
            app.EMERGENCYSTOPONLamp = uilamp(app.Panel_2);
            app.EMERGENCYSTOPONLamp.Enable = 'off';
            app.LAB5CINEMATICAINVERSALabel = uilabel(app.Panel_2);
            app.LAB5CINEMATICAINVERSALabel.Text = 'LAB 5: CINEMATICA INVERSA';
            app.ROBOTICA20231SLabel = uilabel(app.Panel_2);
            app.ROBOTICA20231SLabel.Text = 'ROBOTICA   2023-1S';
            app.AndresMoralesNelsonRamirezAndresZuletaLabel = uilabel(app.Panel_2);
            app.AndresMoralesNelsonRamirezAndresZuletaLabel.Text = 'Andres Morales     Nelson Ramirez      Andres Zuleta';
            app.CLEANButton = uibutton(app.Panel_2, 'push');
            app.CLEANButton.ButtonPushedFcn = createCallbackFcn(app, @CLEANButtonPushed, true);
            app.CLEANButton.Text = 'CLEAN';
            app.INITROSButton = uibutton(app.Panel_2, 'push');
            app.INITROSButton.ButtonPushedFcn = createCallbackFcn(app, @INITROSButtonPushed, true);
            app.INITROSButton.Text = 'INIT ROS';
            app.Panel_3 = uipanel(app.UIFigure);
            app.LOADTOOLButton = uibutton(app.Panel_3, 'push');
            app.LOADTOOLButton.ButtonPushedFcn = createCallbackFcn(app, @LOADTOOLButtonPushed, true);
            app.LOADTOOLButton.Text = 'LOAD TOOL';
            app.UNLOADTOOLButton = uibutton(app.Panel_3, 'push');
            app.UNLOADTOOLButton.ButtonPushedFcn = createCallbackFcn(app, @UNLOADTOOLButtonPushed, true);
            app.UNLOADTOOLButton.Text = 'UNLOAD TOOL';
            app.EMERGENCYSTOPButton = uibutton(app.Panel_3, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = {'EMERGENCY'; 'STOP'};
            app.SELECTROUTUNEPanel = uipanel(app.UIFigure);
            app.SELECTROUTUNEPanel.Title = 'SELECT ROUTUNE';
            app.DRAWNAMEINITIALSButton = uibutton(app.SELECTROUTUNEPanel, 'push');
            app.DRAWNAMEINITIALSButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWNAMEINITIALSButtonPushed, true);
            app.DRAWNAMEINITIALSButton.Text = {'DRAW'; 'NAME'; 'INITIALS'};
            app.DRAWSHAPEButton = uibutton(app.SELECTROUTUNEPanel, 'push');
            app.DRAWSHAPEButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWSHAPEButtonPushed, true);
            app.DRAWSHAPEButton.Text = {'DRAW'; 'SHAPE'};
            app.DRAWWORKSPACELIMITSButton = uibutton(app.SELECTROUTUNEPanel, 'push');
            app.DRAWWORKSPACELIMITSButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWWORKSPACELIMITSButtonPushed, true);
            app.DRAWWORKSPACELIMITSButton.Text = {'DRAW'; 'WORKSPACE'; 'LIMITS'};
            app.OPTIONSPanel = uipanel(app.UIFigure);
            app.OPTIONSPanel.Title = 'OPTIONS';
            app.TabGroup = uitabgroup(app.OPTIONSPanel);
            app.INITIALSTab = uitab(app.TabGroup);
            app.INITIALSTab.Title = 'INITIALS';
            app.NButton = uibutton(app.INITIALSTab, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.MButton = uibutton(app.INITIALSTab, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.ZButton = uibutton(app.INITIALSTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.SHAPETab = uitab(app.TabGroup);
            app.SHAPETab.Title = 'SHAPE';
            app.RECTANGLEButton = uibutton(app.SHAPETab, 'push');
            app.RECTANGLEButton.ButtonPushedFcn = createCallbackFcn(app, @RECTANGLEButtonPushed, true);
            app.RECTANGLEButton.Text = 'RECTANGLE';
            app.CIRCLEButton = uibutton(app.SHAPETab, 'push');
            app.CIRCLEButton.ButtonPushedFcn = createCallbackFcn(app, @CIRCLEButtonPushed, true);
            app.CIRCLEButton.Text = 'CIRCLE';
            app.CURRENTPOSITIONPanel = uipanel(app.UIFigure);
            app.CURRENTPOSITIONPanel.Title = 'CURRENT POSITION';
            app.XLabel = uilabel(app.CURRENTPOSITIONPanel);
            app.XLabel.Text = 'X:';
            app.xPosition = uilabel(app.CURRENTPOSITIONPanel);
            app.xPosition.Text = '0.00';
            app.YLabel = uilabel(app.CURRENTPOSITIONPanel);
            app.YLabel.Text = 'Y:';
            app.yPosition = uilabel(app.CURRENTPOSITIONPanel);
            app.yPosition.Text = '0.00';
            app.ZLabel = uilabel(app.CURRENTPOSITIONPanel);
            app.ZLabel.Text = 'Z:';
            app.zPosition = uilabel(app.CURRENTPOSITIONPanel);
            app.zPosition.Text = '0.00';
            app.INFOPanel = uipanel(app.UIFigure);
            app.INFOPanel.Title = 'INFO';
            app.mensajesDeAlerta = uilabel(app.INFOPanel);
            app.mensajesDeAlerta.Text = '';
            app.IMAGEPanel = uipanel(app.UIFigure);
            app.IMAGEPanel.Title = 'IMAGE';
            app.fotoFigura = uiimage(app.IMAGEPanel);
            app.fotoFigura.ImageSource = fullfile(pathToMLAPP, 'defaultpicture.jpeg');
            app.plotFigura = uiimage(app.IMAGEPanel);
            app.plotFigura.ImageSource = fullfile(pathToMLAPP, 'defaultplot.jpeg');
            app.ROUTINESPanel = uipanel(app.UIFigure);
            app.ROUTINESPanel.Title = 'ROUTINES';
            app.CURRENTROUTINELabel = uilabel(app.ROUTINESPanel);
            app.CURRENTROUTINELabel.Text = 'CURRENT ROUTINE:';
            app.LASTEXECUTEDROUTINELabel = uilabel(app.ROUTINESPanel);
            app.LASTEXECUTEDROUTINELabel.Text = 'LAST EXECUTED ROUTINE:';
            app.LASTROUTINEEXECUTIONTIMELabel = uilabel(app.ROUTINESPanel);
            app.LASTROUTINEEXECUTIONTIMELabel.Text = 'LAST ROUTINE EXECUTION TIME:';
            app.currentRoutine = uilabel(app.ROUTINESPanel);
            app.currentRoutine.Text = '';
            app.lastRoutine = uilabel(app.ROUTINESPanel);
            app.lastRoutine.Text = '';
            app.executionTime = uilabel(app.ROUTINESPanel);
            app.executionTime.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppLab5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end